classdef BC_VARETA < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        BCVARETAToolboxv10UIFigure  matlab.ui.Figure
        FileMenu                    matlab.ui.container.Menu
        DownloadtestdataMenu        matlab.ui.container.Menu
        ImportdataMenu              matlab.ui.container.Menu
        ExitMenu                    matlab.ui.container.Menu
        ToolsMenu                   matlab.ui.container.Menu
        CreateDataStructureMenu     matlab.ui.container.Menu
        LeadFieldComputationMenu    matlab.ui.container.Menu
        SingleSubjectMenu_LF        matlab.ui.container.Menu
        BatchProcessingMenu_LF      matlab.ui.container.Menu
        MEEGAnalysisMenu            matlab.ui.container.Menu
        SingleSubjectMenu_A         matlab.ui.container.Menu
        BatchProcessingMenu_A       matlab.ui.container.Menu
        ViewMenu                    matlab.ui.container.Menu
        FigureMenu                  matlab.ui.container.Menu
        SubjectsresultMenu          matlab.ui.container.Menu
        SubjectsconnectivityMenu    matlab.ui.container.Menu
        SubjectsactivityMenu        matlab.ui.container.Menu
        RealEEGMenu                 matlab.ui.container.Menu
        HelpMenu                    matlab.ui.container.Menu
        TextArea                    matlab.ui.control.TextArea
    end

    
    properties (Access = private)
        Property % Description
    end
    
    properties (Access = public)
        single_subject % Description
    end
    
    methods (Access = private)
        
        function setPromptFcn(app,jTextArea,eventData,newPrompt)
            % Prevent overlapping reentry due to prompt replacement
            persistent inProgress
            if isempty(inProgress)
                inProgress = 1;  %#ok unused
            else
                return;
            end
            
            try
                % *** Prompt modification code goes here ***
                cwText = char(jTextArea.getText);
                app.TextArea.Value = cwText;
                % force prompt-change callback to fizzle-out...
                pause(0.02);
            catch
                % Never mind - ignore errors...
            end
            
            % Enable new callbacks now that the prompt has been modified
            inProgress = [];
            
        end  % setPromptFcn
        
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clc;
            processes = jsondecode(fileread(strcat('app_processes.json')));
            for i = 1: length(processes)
                process = processes(i);
                if(process.active)
                    addpath(process.root_folder);
                end
            end
            try
                jDesktop = com.mathworks.mde.desk.MLDesktop.getInstance;
                jCmdWin = jDesktop.getClient('Command Window');
                jTextArea = jCmdWin.getComponent(0).getViewport.getView;
                set(jTextArea,'CaretUpdateCallback',@app.setPromptFcn)
            catch
                warndlg('fatal error');
            end
        end

        % Menu selected function: CreateDataStructureMenu
        function CreateDataStructureMenuSelected(app, event)
            folder = uigetdir('tittle','Select the Source Folder');
            if(folder==0)
                return;
            end
            create_data_structure(folder);
            msgbox('Completed operation!!!','Info');
        end

        % Callback function
        function ButtonPushed(app, event)
            jDesktop = com.mathworks.mde.desk.MLDesktop.getInstance;
            jCmdWin = jDesktop.getClient('Command Window');
            jTextArea = jCmdWin.getComponent(0).getViewport.getView;
            cwText = char(jTextArea.getText);
            
            set(jTextArea,'CaretUpdateCallback',@myUpdateFcn)
            
        end

        % Menu selected function: ExitMenu
        function ExitMenuSelected(app, event)
            delete(app);
        end

        % Menu selected function: DownloadtestdataMenu
        function DownloadtestdataMenuSelected(app, event)
            folder = uigetdir('tittle','Select the Source Folder');
            if(folder==0)
                return;
            end
            
            f = dialog('Position',[300 300 250 80]);
            
            iconsClassName = 'com.mathworks.widgets.BusyAffordance$AffordanceSize';
            iconsSizeEnums = javaMethod('values',iconsClassName);
            SIZE_32x32 = iconsSizeEnums(2);  % (1) = 16x16,  (2) = 32x32
            jObj = com.mathworks.widgets.BusyAffordance(SIZE_32x32, 'Downloading test data...');  % icon, label
            
            jObj.setPaintsWhenStopped(true);  % default = false
            jObj.useWhiteDots(false);         % default = false (true is good for dark backgrounds)
            javacomponent(jObj.getComponent, [50,10,150,80], f);
            jObj.start;
            pause(1);
            
            app_properties= jsondecode(fileread(strcat('app_properties.json')));
            url = app_properties.generals.test_data_url;
            filename = strcat(folder,filesep,'BC_VARETA_test_data.zip');
            matlab.net.http.HTTPOptions.VerifyServerName = false;
            options = weboptions('Timeout',Inf,'RequestMethod','get');
            
            try
                disp('Downloding test data....');
                outfilename = websave(filename,url,options);
            catch
                delete(f);
                errordlg('Download error!!!','Error');
                return;
            end
            try
                disp('Unpacking test data....');
                exampleFiles = unzip(filename,folder);
            catch
                delete(f);
                errordlg('Unpackage error!!!','Error');
                return;
            end
            jObj.stop;
            jObj.setBusyText('All done!');
            disp('All done....');
            pause(2);
            delete(f);
            msgbox('Download complete','Info');
        end

        % Menu selected function: FigureMenu
        function FigureMenuSelected(app, event)
            
            [file,path] = uigetfile('*.fig');
            if isequal(file,0)
                disp('User selected Cancel');
                return;
            end
            openfig(strcat(path,filesep,file));
        end

        % Menu selected function: SubjectsresultMenu
        function SubjectsresultMenuSelected(app, event)
            folder = uigetdir('tittle','Select the Source Folder');
            if(folder==0)
                return;
            end
            files = dir(folder);
            ext='.fig';
            for j=1:size(files,1)
                file_name = files(j).name;
                file_path = strcat(folder,filesep, file_name);
                [~,name,ex]=fileparts(file_name);
                %% ----------Searching de data files ------------------------------------
                if(~isfolder(file_path) & strcmpi(strtrim(ex),ext) )
                    openfig(strcat(file_path));
                end
            end
        end

        % Menu selected function: RealEEGMenu
        function RealEEGMenuSelected(app, event)
            %             [file,path] = uigetfile('*.mat');
            %             if isequal(file,0)
            %                 disp('User selected Cancel');
            %                 return;
            %             end
            %             real_EEG=load(strcat(path,filesep,file));
            %
            %             figure_EEG = figure('Color','k','Name',file,'NumberTitle','off','units','normalized','outerposition',[0 0 1 1]);
            %             subplot(1,3,1); plot(real_EEG.Sjj);
            %             %             ylabel('generators')
            %             %     xlabel('generators')
            %             %     title('simulated partial correlations')
            %             subplot(1,3,2); plot(real_EEG.Thetajj);
            %             %             ylabel('generators')
            %             %     xlabel('generators')
            %             %     title('simulated partial correlations')
            %             subplot(1,3,3); plot(real_EEG.indms);
            
        end

        % Menu selected function: SingleSubjectMenu_A
        function SingleSubjectMenu_ASelected(app, event)
            addpath('functions');
            bcv_properties = jsondecode(fileread(fullfile('bcv_properties','bcv_properties.json')));
            bcv_properties.run_single_subject.value = true;
            saveJSON(bcv_properties,fullfile('bcv_properties','bcv_properties.json'));
            BC_VARETA_bash;
            msgbox('Completed operation!!!','Info');
        end

        % Menu selected function: BatchProcessingMenu_A
        function BatchProcessingMenu_ASelected(app, event)
            bcv_properties = jsondecode(fileread(fullfile('bcv_properties','bcv_properties.json')));
            bcv_properties.run_single_subject.value = false;
            saveJSON(bcv_properties,fullfile('bcv_properties','bcv_properties.json'));
            BC_VARETA_bash;
            msgbox('Completed operation!!!','Info');
        end

        % Menu selected function: SingleSubjectMenu_LF
        function SingleSubjectMenu_LFSelected(app, event)
%             addpath('bst_lf_ppl');
%             bs_lf_ppl;
%             msgbox('Completed operation!!!','Info');
        end

        % Menu selected function: BatchProcessingMenu_LF
        function BatchProcessingMenu_LFSelected(app, event)
%             addpath('bst_lf_ppl');
%             bs_lf_ppl;
%             msgbox('Completed operation!!!','Info');
        end

        % Menu selected function: SubjectsconnectivityMenu
        function SubjectsconnectivityMenuSelected(app, event)
            folder = uigetdir('tittle','Select the Subject''s result folder');
            if(folder==0)
                return;
            end
            files = dir(folder);
            ext='.fig';
            for j=1:size(files,1)
                file_name = files(j).name;
                file_path = strcat(folder,filesep, file_name);
                [~,name,ex]=fileparts(file_name);
                %% ----------Searching de data files ------------------------------------
                if(~isfolder(file_path) & strcmpi(strtrim(ex),ext) & contains(file_name,'roi_conn'))
                    openfig(strcat(file_path));
                end
            end
        end

        % Menu selected function: SubjectsactivityMenu
        function SubjectsactivityMenuSelected(app, event)
            folder = uigetdir('tittle','Select the Subject''s result folder');
            if(folder==0)
                return;
            end
            files = dir(folder);
            ext='.fig';
            for j=1:size(files,1)
                file_name = files(j).name;
                file_path = strcat(folder,filesep, file_name);
                [~,name,ex]=fileparts(file_name);
                %% ----------Searching de data files ------------------------------------
                if(~isfolder(file_path) & strcmpi(strtrim(ex),ext) & contains(file_name,'activity') )
                    openfig(strcat(file_path));
                end
            end
        end

        % Menu selected function: ImportdataMenu
        function ImportdataMenuSelected(app, event)
            import_data_structure;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.BCVARETAToolboxv10UIFigure = uifigure;
            app.BCVARETAToolboxv10UIFigure.Name = 'BC-VARETA Toolbox v1.0';
            app.FileMenu = uimenu(app.BCVARETAToolboxv10UIFigure);
            app.FileMenu.Text = 'File';
            app.DownloadtestdataMenu = uimenu(app.FileMenu);
            app.DownloadtestdataMenu.MenuSelectedFcn = createCallbackFcn(app, @DownloadtestdataMenuSelected, true);
            app.DownloadtestdataMenu.Text = 'Download test data';
            app.ImportdataMenu = uimenu(app.FileMenu);
            app.ImportdataMenu.MenuSelectedFcn = createCallbackFcn(app, @ImportdataMenuSelected, true);
            app.ImportdataMenu.Text = 'Import data';
            app.ExitMenu = uimenu(app.FileMenu);
            app.ExitMenu.MenuSelectedFcn = createCallbackFcn(app, @ExitMenuSelected, true);
            app.ExitMenu.Text = 'Exit';
            app.ToolsMenu = uimenu(app.BCVARETAToolboxv10UIFigure);
            app.ToolsMenu.Text = 'Tools';
            app.CreateDataStructureMenu = uimenu(app.ToolsMenu);
            app.CreateDataStructureMenu.MenuSelectedFcn = createCallbackFcn(app, @CreateDataStructureMenuSelected, true);
            app.CreateDataStructureMenu.Text = 'Create Data Structure';
            app.LeadFieldComputationMenu = uimenu(app.ToolsMenu);
            app.LeadFieldComputationMenu.Text = 'Lead Field Computation';
            app.SingleSubjectMenu_LF = uimenu(app.LeadFieldComputationMenu);
            app.SingleSubjectMenu_LF.MenuSelectedFcn = createCallbackFcn(app, @SingleSubjectMenu_LFSelected, true);
            app.SingleSubjectMenu_LF.Text = 'Single Subject';
            app.BatchProcessingMenu_LF = uimenu(app.LeadFieldComputationMenu);
            app.BatchProcessingMenu_LF.MenuSelectedFcn = createCallbackFcn(app, @BatchProcessingMenu_LFSelected, true);
            app.BatchProcessingMenu_LF.Text = 'Batch Processing';
            app.MEEGAnalysisMenu = uimenu(app.ToolsMenu);
            app.MEEGAnalysisMenu.Text = 'MEEG Analysis';
            app.SingleSubjectMenu_A = uimenu(app.MEEGAnalysisMenu);
            app.SingleSubjectMenu_A.MenuSelectedFcn = createCallbackFcn(app, @SingleSubjectMenu_ASelected, true);
            app.SingleSubjectMenu_A.Text = 'Single Subject';
            app.BatchProcessingMenu_A = uimenu(app.MEEGAnalysisMenu);
            app.BatchProcessingMenu_A.MenuSelectedFcn = createCallbackFcn(app, @BatchProcessingMenu_ASelected, true);
            app.BatchProcessingMenu_A.Text = 'Batch Processing';
            app.ViewMenu = uimenu(app.BCVARETAToolboxv10UIFigure);
            app.ViewMenu.Text = 'View';
            app.FigureMenu = uimenu(app.ViewMenu);
            app.FigureMenu.MenuSelectedFcn = createCallbackFcn(app, @FigureMenuSelected, true);
            app.FigureMenu.Text = 'Figure';
            app.SubjectsresultMenu = uimenu(app.ViewMenu);
            app.SubjectsresultMenu.MenuSelectedFcn = createCallbackFcn(app, @SubjectsresultMenuSelected, true);
            app.SubjectsresultMenu.Text = 'Subject''s result';
            app.SubjectsconnectivityMenu = uimenu(app.ViewMenu);
            app.SubjectsconnectivityMenu.MenuSelectedFcn = createCallbackFcn(app, @SubjectsconnectivityMenuSelected, true);
            app.SubjectsconnectivityMenu.Text = 'Subject''s connectivity';
            app.SubjectsactivityMenu = uimenu(app.ViewMenu);
            app.SubjectsactivityMenu.MenuSelectedFcn = createCallbackFcn(app, @SubjectsactivityMenuSelected, true);
            app.SubjectsactivityMenu.Text = 'Subject''s activity';
            app.RealEEGMenu = uimenu(app.ViewMenu);
            app.RealEEGMenu.MenuSelectedFcn = createCallbackFcn(app, @RealEEGMenuSelected, true);
            app.RealEEGMenu.Text = 'Real EEG ';
            app.HelpMenu = uimenu(app.BCVARETAToolboxv10UIFigure);
            app.HelpMenu.Text = 'Help';
            app.TextArea = uitextarea(app.BCVARETAToolboxv10UIFigure);
        end
    end

    methods (Access = public)

        % Construct app
        function app = BC_VARETA

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.BCVARETAToolboxv10UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.BCVARETAToolboxv10UIFigure)
        end
    end
end