classdef ExploreDS < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ExploreDatasetsUIFigure  matlab.ui.Figure
        Toolbar                  matlab.ui.container.Toolbar
        PushTool                 matlab.ui.container.toolbar.PushTool
        GeneralInformationPanel  matlab.ui.container.Panel
        DSDescriptionTextArea    matlab.ui.control.TextArea
        DescriptionLabel         matlab.ui.control.Label
        DSNameLabel              matlab.ui.control.Label
        DatasetsPanel            matlab.ui.container.Panel
        DatasetsTree             matlab.ui.container.Tree
        Node                     matlab.ui.container.TreeNode
        Node2                    matlab.ui.container.TreeNode
        Node3                    matlab.ui.container.TreeNode
        Node4                    matlab.ui.container.TreeNode
        Node_2                   matlab.ui.container.TreeNode
        Node2_2                  matlab.ui.container.TreeNode
        Node3_2                  matlab.ui.container.TreeNode
        Node4_2                  matlab.ui.container.TreeNode
        CloseButton              matlab.ui.control.Button
        SubjectInformationPanel  matlab.ui.container.Panel
        UIAxes                   matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        Datasets; % Description
        BCVdir;
        Datasets_file;
    end
    
    properties (Access = private)
        currentDS % Description
    end
    
    methods (Access = private)
        
        function ParticipantSensorLevel(app,menu)
            selectedNodes = app.DatasetsTree.SelectedNodes;
            disp(selectedNodes.Text);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, callerApp, Datasets)
            app.Datasets_file = fullfile(callerApp.BCVdir,"Datasets.json");
            AllDatasets = jsondecode(fileread(app.Datasets_file));
            delete(app.DatasetsTree.Children);                
            app.Datasets = Datasets;
            for i=1:length(app.Datasets)
                app.currentDS = app.Datasets(i);
                child = uitreenode(app.DatasetsTree,'Text',app.currentDS.Name);
                child.Icon = 'guide/images/box-solid.png';                
                indexDS = find(contains({AllDatasets.UUID},app.currentDS.UUID),1);
                participants = AllDatasets(indexDS).Participants;
                for j=1:length(participants)
                    participant = participants(j);
                    subChild = uitreenode(child,'Text',participant.SubID);                    
                    subChild.Icon = 'guide/images/participant.png';
                    if(isequal(participant.Status,"Completed"))
                        mStyle = uistyle("FontColor","blue");
                    else
                        mStyle = uistyle("FontColor","red");
                    end
                    addStyle(app.DatasetsTree,mStyle,"node",subChild)
                    contexMenu = uicontextmenu(app.ExploreDatasetsUIFigure);
                    if(isequal(participant.Status,"Completed"))
                        PartInfo = load(fullfile(app.currentDS.Path,participant.SubID,participant.FileInfo));
                        processes = PartInfo.Processes;
                        for k=1:length(processes)
                            process = processes(k);
                            if(process.completed)
                                menu = uimenu(contexMenu,'Text',process.name, "MenuSelectedFcn",@ParticipantSensorLevel);                                
                            end
                        end
                        subChild.ContextMenu = contexMenu;
                    end
                end
            end
            expand(app.DatasetsTree);
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            delete(app);
        end

        % Selection changed function: DatasetsTree
        function DatasetsTreeSelectionChanged(app, event)
            selectedNodes = app.DatasetsTree.SelectedNodes;
            app.DSNameLabel.Text = strcat("Name: ", app.currentDS.Name);
            app.DSDescriptionTextArea.Value = app.currentDS.Description;
            participant = app.currentDS.Participants(find( ...
                contains( ...
                {app.currentDS.Participants.SubID},selectedNodes.Text),1));
            PartInfo = load(fullfile(app.currentDS.Path,participant.SubID,participant.FileInfo));
            disp(PartInfo);


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.ExploreDatasetsUIFigure = uifigure('Visible', 'off');
            app.ExploreDatasetsUIFigure.Name = 'Explore Datasets';
            app.Toolbar = uitoolbar(app.ExploreDatasetsUIFigure);
            app.PushTool = uipushtool(app.Toolbar);
            app.UIAxes = uiaxes(app.ExploreDatasetsUIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.SubjectInformationPanel = uipanel(app.ExploreDatasetsUIFigure);
            app.SubjectInformationPanel.Title = 'Subject Information';
            app.CloseButton = uibutton(app.ExploreDatasetsUIFigure, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            app.DatasetsPanel = uipanel(app.ExploreDatasetsUIFigure);
            app.DatasetsPanel.Title = 'Datasets';
            app.DatasetsTree = uitree(app.DatasetsPanel);
            app.DatasetsTree.SelectionChangedFcn = createCallbackFcn(app, @DatasetsTreeSelectionChanged, true);
            app.Node = uitreenode(app.DatasetsTree);
            app.Node.Text = 'Node';
            app.Node2 = uitreenode(app.Node);
            app.Node2.Icon = fullfile(pathToMLAPP, 'images', 'participant.png');
            app.Node2.Text = 'Node2';
            app.Node3 = uitreenode(app.Node);
            app.Node3.Text = 'Node3';
            app.Node4 = uitreenode(app.Node);
            app.Node4.Text = 'Node4';
            app.Node_2 = uitreenode(app.DatasetsTree);
            app.Node_2.Text = 'Node';
            app.Node2_2 = uitreenode(app.Node_2);
            app.Node2_2.Text = 'Node2';
            app.Node3_2 = uitreenode(app.Node_2);
            app.Node3_2.Text = 'Node3';
            app.Node4_2 = uitreenode(app.Node_2);
            app.Node4_2.Text = 'Node4';
            app.GeneralInformationPanel = uipanel(app.ExploreDatasetsUIFigure);
            app.GeneralInformationPanel.Title = 'General Information';
            app.DSNameLabel = uilabel(app.GeneralInformationPanel);
            app.DSNameLabel.Text = 'Name:';
            app.DescriptionLabel = uilabel(app.GeneralInformationPanel);
            app.DescriptionLabel.Text = 'Description:';
            app.DSDescriptionTextArea = uitextarea(app.GeneralInformationPanel);
            app.DSDescriptionTextArea.Editable = 'off';
            % Show the figure after all components are created
            app.ExploreDatasetsUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ExploreDS(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ExploreDatasetsUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ExploreDatasetsUIFigure)
        end
    end
end